classdef Cons3APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Slider               matlab.ui.control.Slider
        SliderLabel          matlab.ui.control.Label
        beta3EditField       matlab.ui.control.NumericEditField
        betaEditFieldLabel   matlab.ui.control.Label
        alpha3EditField      matlab.ui.control.NumericEditField
        alphaEditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            a = app.Slider.Value;

            [Bx,By,Bz] = creat3();
            b = (1-3*a)/6;
            app.alpha3EditField.Value = a;
            app.beta3EditField.Value = b;

            BX = filltriangle(Bx, a);
            BY = filltriangle(By, a);
            BZ = filltriangle(Bz, a);

            [TRI, U] = trimeshgrid(50);
            b = bezier3(BX,BY,BZ,U);

            trisurf(TRI,b(:,1),b(:,2),b(:,3), 'parent',app.UIAxes);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alpha3EditField = uieditfield(app.UIFigure, 'numeric');
            app.betaEditFieldLabel = uilabel(app.UIFigure);
            app.betaEditFieldLabel.Text = 'beta';
            app.beta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-0.5 0.1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cons3APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end