classdef ConsPatchAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        betaEditField       matlab.ui.control.NumericEditField
        betaEditFieldLabel  matlab.ui.control.Label
        alphaEditField      matlab.ui.control.NumericEditField
        alphaLabel          matlab.ui.control.Label
        AlphaSlider         matlab.ui.control.Slider
        AlphaSliderLabel    matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)

            a = app.AlphaSlider.Value;
            b = 1/4 - a;
            app.alphaEditField.Value = a;
            app.betaEditField.Value = b;
            [Bx,By,Bz] = creatB9B7();
            BX = fillsqare(Bx, a, b);
            BY = fillsqare(By, a, b);
            BZ = fillsqare(Bz, a, b);
            
            u = linspace(0,1,100);
            v = linspace(0,1,100);

            [bx, by, bz] = bezier2(BX,BY,BZ,u,v);


            %surf(bx, by, bz, 'parent', app.UIAxes);
            mesh(bx, by, bz, 'parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cons Patch')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AlphaSliderLabel = uilabel(app.UIFigure);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.UIFigure);
            app.AlphaSlider.Limits = [-0.5 0.1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.alphaLabel = uilabel(app.UIFigure);
            app.alphaLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.betaEditFieldLabel = uilabel(app.UIFigure);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConsPatchAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end